classdef SuspensionSolverBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnterSuspensionGeometryCoordinatesPanel  matlab.ui.container.Panel
        WheelRadiusEditField            matlab.ui.control.NumericEditField
        WheelRadiusEditFieldLabel       matlab.ui.control.Label
        wheelbase0roll_center_heightLabel  matlab.ui.control.Label
        roll_center_heightLabel         matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        TyreContactpointEditField_2     matlab.ui.control.EditField
        TyreContactpointEditField_2Label  matlab.ui.control.Label
        TyreContactpointEditField       matlab.ui.control.EditField
        TyreContactpointEditFieldLabel  matlab.ui.control.Label
        RollCenterEditField_2           matlab.ui.control.EditField
        RollCenterEditField_2Label      matlab.ui.control.Label
        RearGeometryLabel               matlab.ui.control.Label
        BellCrankRotatingpointEditField_2  matlab.ui.control.EditField
        BellCrankRotatingpointEditField_2Label  matlab.ui.control.Label
        StaticToeEditField_2            matlab.ui.control.EditField
        StaticToeEditField_2Label       matlab.ui.control.Label
        StaticCamberEditField_2         matlab.ui.control.EditField
        StaticCamberEditField_2Label    matlab.ui.control.Label
        DampertoBellCrankpointEditField_2  matlab.ui.control.EditField
        DampertoBellCrankpointEditField_2Label  matlab.ui.control.Label
        DampertobodypointEditField_2    matlab.ui.control.EditField
        DampertobodypointEditField_2Label  matlab.ui.control.Label
        InnertrackrodballjointEditField_2  matlab.ui.control.EditField
        InnertrackrodballjointEditField_2Label  matlab.ui.control.Label
        OutertrackrodballjointEditField_2  matlab.ui.control.EditField
        OutertrackrodballjointEditField_2Label  matlab.ui.control.Label
        UpperwishbonefrontpivotEditField_2  matlab.ui.control.EditField
        UpperwishbonefrontpivotEditField_2Label  matlab.ui.control.Label
        PushrodBellCrankendEditField_2  matlab.ui.control.EditField
        PushrodBellCrankendEditField_2Label  matlab.ui.control.Label
        PushrodwishboneendEditField_2   matlab.ui.control.EditField
        PushrodwishboneendEditField_2Label  matlab.ui.control.Label
        UpperwishboneouterballjointEditField_2  matlab.ui.control.EditField
        UpperwishboneouterballjointEditField_2Label  matlab.ui.control.Label
        UpperwishbonerearpivotEditField_2  matlab.ui.control.EditField
        UpperwishbonerearpivotEditField_2Label  matlab.ui.control.Label
        LowerwishboneouterballjointEditField_2  matlab.ui.control.EditField
        LowerwishboneouterballjointEditField_2Label  matlab.ui.control.Label
        LowerwishbonerearpivotEditField_2  matlab.ui.control.EditField
        LowerwishbonerearpivotEditField_2Label  matlab.ui.control.Label
        LowerwishbonefrontpivotEditField_2  matlab.ui.control.EditField
        LowerwishbonefrontpivotEditField_2Label  matlab.ui.control.Label
        RollCenterEditField             matlab.ui.control.EditField
        RollCenterEditFieldLabel        matlab.ui.control.Label
        BellCrankRotatingpointEditField  matlab.ui.control.EditField
        BellCrankRotatingpointEditFieldLabel  matlab.ui.control.Label
        StaticToeEditField              matlab.ui.control.EditField
        StaticToeEditFieldLabel         matlab.ui.control.Label
        StaticCamberEditField           matlab.ui.control.EditField
        StaticCamberEditFieldLabel      matlab.ui.control.Label
        DampertoBellCrankpointEditField  matlab.ui.control.EditField
        DampertoBellCrankpointEditFieldLabel  matlab.ui.control.Label
        DampertobodypointEditField      matlab.ui.control.EditField
        DampertobodypointEditFieldLabel  matlab.ui.control.Label
        FrontGeometryLabel              matlab.ui.control.Label
        InnertrackrodballjointEditField  matlab.ui.control.EditField
        InnertrackrodballjointEditFieldLabel  matlab.ui.control.Label
        OutertrackrodballjointEditField  matlab.ui.control.EditField
        OutertrackrodballjointEditFieldLabel  matlab.ui.control.Label
        UpperwishbonefrontpivotEditField  matlab.ui.control.EditField
        UpperwishbonefrontpivotEditFieldLabel  matlab.ui.control.Label
        PushrodBellCrankendEditField    matlab.ui.control.EditField
        PushrodBellCrankendEditFieldLabel  matlab.ui.control.Label
        PushrodwishboneendEditField     matlab.ui.control.EditField
        PushrodwishboneendEditFieldLabel  matlab.ui.control.Label
        UpperwishboneouterballjointEditField  matlab.ui.control.EditField
        UpperwishboneouterballjointEditFieldLabel  matlab.ui.control.Label
        UpperwishbonerearpivotEditField  matlab.ui.control.EditField
        UpperwishbonerearpivotEditFieldLabel  matlab.ui.control.Label
        LowerwishboneouterballjointEditField  matlab.ui.control.EditField
        LowerwishboneouterballjointEditFieldLabel  matlab.ui.control.Label
        LowerwishbonerearpivotEditField  matlab.ui.control.EditField
        LowerwishbonerearpivotEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LowerwishbonefrontpivotEditField  matlab.ui.control.EditField
        LowerwishbonefrontpivotEditFieldLabel  matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        ModespecifiesthetypeofparameterLabel  matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        DisplayforLeftRearTyreButton    matlab.ui.control.Button
        DisplayforRightRearTyreButton   matlab.ui.control.Button
        DisplayforRightFrontTyreButton  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DisplayforLeftFrontTyreButton   matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        NumberofvaluesEditField         matlab.ui.control.NumericEditField
        NumberofvaluesEditFieldLabel    matlab.ui.control.Label
        ModeEditField                   matlab.ui.control.NumericEditField
        ModeEditFieldLabel              matlab.ui.control.Label
        ValueEditField                  matlab.ui.control.NumericEditField
        ValueEditFieldLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ans % Description
        front
        rear
        whlradius % Description
    end
    
    methods (Access = private)
        
        function z=sustravel(app,T,In,ty)

%T has inputs of target angles in degrees
% T(1) is upper ball joint rotation angle
% T(2),T(3),T(4) is the full knuckle rotation mapped
% T(5) is lower ball joint rotation angle
% 
% In has the required inputs for the code
% In(1) has the bump offered by the road in mm
% In(2) has the length of movement by tie rod inboard point in mm
% In(3) has the roll experienced by the car in degrees


uj1=ty.uj1;%----------------------------------------upper inboard point 1
uj2=ty.uj2;%-------------------------------------upper inboard point 2
lj1=ty.lj1;%--------------------------------------lower inboard point 1
lj2=ty.lj2;%-------------------------------------lower inboard point 2
lbj=ty.lbj;%--------------------------------------lower ball joint
ubj=ty.ubj;%---------------------------------------upper ball joint
TRI=ty.TRI;%-----------------------------------tie rod chassis point
TRO=ty.TRO;%-------------steering arm point (where tie rod attatches)
whlcenter=ty.whlcenter;%-------------------------------define wheel center
spindlevec=ty.spindlevec;
PRI=ty.PRI;
PRO=ty.PRO;
BD=ty.BD;
Bellcrankpoint=ty.BellAxis;


rcf=ty.rcf;%------------------------------------------------------roll center front
rcr=ty.rcr;%------------------------------------------------------roll center rear

roadprof=[0,0,1,0];%-----------------------------------------------road plane initial


spindle=whlcenter-lbj;%--------------------------------------------define spindle/axle

savector=ubj-lbj;%-------------------------------------------------define initial steering axis vector

BellAxis=cross((BD-Bellcrankpoint),(PRI-Bellcrankpoint));
stvector=TRO-lbj;%--------------------------------------------define steering arm vector


%defined the intial coordinates now we move onto functions

ubjf=rotation(app,uj1,uj2,ubj,T(1));%------------------------calculate the final position for upper ball joint
PROfin=rotation(app,uj1,uj2,PRO,T(1));
lbjf=rotation(app,lj1,lj2,lbj,T(5));%------------------------calculate the final position for lower ball joint

rotaxis1=[savector(2),-savector(1),0];%------------------calculate axis about which we rotate the steering axis-spindle rigid body for the first time using Angle1

stvectorfinal=rotate3D(app,stvector,rotaxis1,T(2));%---------calculate intermediate rotated steering arm
spindlefinal=rotate3D(app,spindle,rotaxis1,T(2));%-----------calculate intermediate rotated spindle
savectorinter=rotate3D(app,savector,rotaxis1,T(2));%---------calculate intermediate rotated steering axis
tierodcpfin=TRI-[0,In(2),0];
spindlevecfin=rotate3D(app,spindlevec,rotaxis1,T(2));

rotaxis2=cross(savectorinter,rotaxis1);%-----------------calculate axis about which we rotate the steering axis-spindle rigid body for the second time using Angle2

stvectorfinal=rotate3D(app,stvectorfinal,rotaxis2,T(3));%----calculate final steering arm vector
spindlefinal=rotate3D(app,spindlefinal,rotaxis2,T(3));%------calculate final spindle vector
savectorfinal=rotate3D(app,savectorinter,rotaxis2,T(3));%----calculate final steering axis vector
spindlevecfin=rotate3D(app,spindlevecfin,rotaxis2,T(3));

spindlefinal=rotate3D(app,spindlefinal,savectorfinal,T(4));%-spindle rotation final
stvectorfinal=rotate3D(app,stvectorfinal,savectorfinal,T(4));%steering arm roation final
spindlevecfin=rotate3D(app,spindlevecfin,savectorfinal,T(4));

theta = linspace(0, 2 * pi, 361);
tyreplane1=[spindlevecfin(2),-spindlevecfin(1),0];
tyreplane1n=tyreplane1/norm(tyreplane1);
tyreplane2=cross(spindlevecfin,tyreplane1n);


PRIfin=rotate3D(app,(PRI-Bellcrankpoint),BellAxis,T(6));

PRIfin=PRIfin+Bellcrankpoint;

roadproffin=(roadroll(app,rcr,rcf,In(3),roadprof));%--------road final (roll)
% roadproffin=(roadroll(pcr,pcl,In(4),roadproffin));%--------road final (pitch)
% 
roadproffin=roadproffin-[0,0,0,In(1)];
for i =1:361
     tyre=lbjf+spindlefinal+tyreplane1n*app.whlradius*cos(theta(i))+tyreplane2*app.whlradius*sin(theta(i));
     t(i)=norm(pointPlaneDistance(app,tyre,roadproffin+[0,0,0,200]));

end
[~,y]=min(t);
tyrecpfin=lbjf+spindlefinal+tyreplane1n*app.whlradius*cos(theta(y))+tyreplane2*app.whlradius*sin(theta(y));
%to calculate the final value we will rotate the final by angle T(5) and
%find 5th equation using tie rod points.
%now we define the five equations or constraints


eq1=lbjf(1)+savectorfinal(1)-ubjf(1);%------------- x coordinate constraint
eq2=lbjf(2)+savectorfinal(2)-ubjf(2);%------------- y coordinate constraint
eq3=lbjf(3)+savectorfinal(3)-ubjf(3);%------------- z coordinate constraint
%eq4=lbjf(3)+spindlefinal(3)-lbj(3)-spindle(3)-whltravel;%-- wheel travel constraint
eq4=norm(stvector+lbj-TRI)-norm(stvectorfinal+lbjf-tierodcpfin);%--tierod constraint
eq5=pointPlaneDistance(app,tyrecpfin,roadproffin);%------------------------------------ road input
eq6=norm(PRI-PRO)-norm(PRIfin-PROfin);
%eq6=norm(Dbody-BD)-norm(Dbody-BDfin)-In(1);

z=[eq1;eq2;eq3;eq4;eq5;eq6];
%=[eq1;eq2;eq3;eq4];
        end
        function z=roadroll(~,rcf,rcr,ang,road)
axisVector=rcf-rcr;
axisMagnitude = norm(axisVector);
axisUnitVector = axisVector / axisMagnitude;

% Specify the angle of rotation in radians
angleRadians = deg2rad(ang); % Rotate road by given input roll degrees

% Define the skew-symmetric matrix K
u = axisUnitVector(1);
v = axisUnitVector(2);
w = axisUnitVector(3);
K = [0, -w, v; w, 0, -u; -v, u, 0];

% Calculate sin(theta) and cos(theta)
sinTheta = sin(angleRadians);
cosTheta = cos(angleRadians);

% Calculate the Rodrigues' rotation matrix
R = eye(3) + sinTheta * K + (1 - cosTheta) * (K^2);

%Translation of points
translation = rcf - rcf * R;
R=[R,translation'];
R=[R;[0,0,0,1]];


% Apply the combined transformation, including both rotation and translation, to the coefficients of the plane equation
rotatedCoefficients = road* R;

% Extract the coefficients of the rotated plane
% rotatedA = rotatedCoefficients(1);
% rotatedB = rotatedCoefficients(2);
% rotatedC = rotatedCoefficients(3);
% rotatedD = rotatedCoefficients(4);
z=rotatedCoefficients;
%disp(z);
% Display the equation of the rotated plane
%fprintf('Equation of the rotated plane: %.4fx + %.4fy + %.4fz + %.4f = 0\n', rotatedA, rotatedB, rotatedC, rotatedD);

end
function z=finsusp(app,T,In,ty)

app.whlradius;

lj1=ty.lj1;%--------------------------------------lower inboard point 1
lj2=ty.lj2;%-------------------------------------lower inboard point 2
lbj=ty.lbj;%--------------------------------------lower ball joint
ubj=ty.ubj;%---------------------------------------upper ball joint

tcp=ty.tcp;
whltrack=abs(ty.tcp(2)*2);
whlcenter=ty.whlcenter;%-------------------------------define wheel center
spindlevec=ty.spindlevec;
PRI=ty.PRI;%pull rod bellcrank point (to be found)
BD=ty.BD ;
Dbody=ty.Dbody ;
Bellcrankpoint=ty.BellAxis;

origin=[tcp(1),0,0];


spindle=whlcenter-lbj;%--------------------------------------------define spindle/axle

savector=ubj-lbj;%-------------------------------------------------define initial steering axis vector
BellAxis=cross((BD-Bellcrankpoint),(PRI-Bellcrankpoint));


rcf=ty.rcf;%------------------------------------------------------roll center front
rcr=ty.rcr;%------------------------------------------------------roll center rear

roadprof=[0,0,1,0];
caryz=[1,0,0,0];
carxz=[0,1,0,0];

%defined the intial coordinates now we move onto functions

lbjf=rotation(app,lj1,lj2,lbj,T(5));%------------------------calculate the final position for lower ball joint
rotaxis1=[savector(2),-savector(1),0];%------------------calculate axis about which we rotate the steering axis-spindle rigid body for the first time using Angle1

spindlefinal=rotate3D(app,spindle,rotaxis1,T(2));%-----------calculate intermediate rotated spindle
savectorinter=rotate3D(app,savector,rotaxis1,T(2));%---------calculate intermediate rotated steering axis
spindlevecfin=rotate3D(app,spindlevec,rotaxis1,T(2));%-------for camber change


rotaxis2=cross(savectorinter,rotaxis1);%-----------------calculate axis about which we rotate the steering axis-spindle rigid body for the second time using Angle2

spindlefinal=rotate3D(app,spindlefinal,rotaxis2,T(3));%------calculate final spindle vector
savectorfinal=rotate3D(app,savectorinter,rotaxis2,T(3));%----calculate final steering axis vector
spindlevecfin=rotate3D(app,spindlevecfin,rotaxis2,T(3));%---- for camber change

spindlefinal=rotate3D(app,spindlefinal,savectorfinal,T(4));%-spindle rotation final
spindlevecfin=rotate3D(app,spindlevecfin,savectorfinal,T(4));

% to calculate final tyrecontact point

theta = linspace(0, 2 * pi, 361);
tyreplane1=[spindlevecfin(2),-spindlevecfin(1),0];
tyreplane1n=tyreplane1/norm(tyreplane1);
tyreplane2=cross(spindlevecfin,tyreplane1n);
roadproffin=(roadroll(app,rcr,rcf,In(3),roadprof));%--------road final (roll)

carxzfin=(roadroll(app,rcr,rcf,In(3),carxz));
caryzfin=(roadroll(app,rcr,rcf,In(3),caryz));

originfin=rotate3D(app,origin-rcf,rcr-rcf,In(3));
originfin=originfin+rcf;


for i =1:361
     tyre=lbjf+spindlefinal+tyreplane1n*app.whlradius*cos(theta(i))+tyreplane2*app.whlradius*sin(theta(i));
     t(i)=norm(pointPlaneDistance(app,tyre,roadproffin+[0,0,0,200]));

end
[~,y]=min(t);
tyrecpfin=lbjf+spindlefinal+tyreplane1n*app.whlradius*cos(theta(y))+tyreplane2*app.whlradius*sin(theta(y));

tcpfin=rotate3D(app,tcp-rcf,rcr-rcf,In(3));
tcpfin=rcf+tcpfin;

BDfin=rotate3D(app,(BD-Bellcrankpoint),BellAxis,T(6));
BDfin=BDfin+Bellcrankpoint;

roadvecfin=[roadproffin(1);roadproffin(2);roadproffin(3)];
carxzvecfin=[carxzfin(1);carxzfin(2);carxzfin(3)];
caryzvecfin=[caryzfin(1);caryzfin(2);caryzfin(3)];


A=[caryzvecfin,carxzvecfin,roadvecfin];
spindlevecfinrot=spindlevecfin*A;
savectorfinrot=savectorfinal*A;
camber=acosd(dot(transpose(roadvecfin),spindlevecfin)/norm(roadvecfin)*norm(spindlevecfin));
camber=90-camber;

castor=atand(savectorfinrot(1)/savectorfinrot(3));
kpi=atand(savectorfinrot(2)/savectorfinrot(3));

%compiling results (only difference is in half track deviation for roll
%which was a bit off)
if (In(3)~=0)
z=[camber,atand(spindlevecfinrot(1)/spindlevecfinrot(2)),-castor,-kpi,norm(Dbody-BD)-norm(Dbody-BDfin),norm(tyrecpfin-originfin)-whltrack/2,tyrecpfin(1)-tcpfin(1)];
else
    z=[camber,atand(spindlevecfinrot(1)/spindlevecfinrot(2)),-castor,-kpi,norm(Dbody-BD)-norm(Dbody-BDfin),abs(tyrecpfin(2))-whltrack/2,tyrecpfin(1)-tcpfin(1)];
end

end
function z=changeup(~,vec)
    z=[vec(1),-vec(2),vec(3)];
end
function main=LotusShark(app)
wheelradius=app.whlradius;
In =(app.ValueEditField.Value);
mode=(app.ModeEditField.Value);
num=(app.NumberofvaluesEditField.Value);

%Left Front tyre
lf.uj1=app.front.uj1;%----------------------------------------upper inboard point 1
lf.uj2=app.front.uj2;%-------------------------------------upper inboard point 2
lf.lj1=app.front.lj1;%--------------------------------------lower inboard point 1
lf.lj2=app.front.lj2;%-------------------------------------lower inboard point 2
lf.lbj=app.front.lbj;%--------------------------------------lower ball joint
lf.ubj=app.front.ubj;%---------------------------------------upper ball joint
lf.TRI=app.front.TRI;%-----------------------------------tie rod chassis point
lf.TRO=app.front.TRO;%-------------steering arm point (where tie rod attatches)
lf.C=app.front.Camber;
lf.Toe=app.front.Toe;
lf.tcp=app.front.tcp;
lf.whlcenter=[wheelradius*sind(lf.C)*sind(lf.Toe),wheelradius*sind(lf.C)*cosd(lf.Toe),wheelradius*cosd(lf.C)]+lf.tcp;%-------------------------------define wheel center
lf.spindlevec=[-cosd(lf.C)*sind(lf.Toe),-cosd(lf.C)*cosd(lf.Toe),sind(lf.C)];
lf.PRI=app.front.PRI;%pull rod bellcrank point (to be found)
lf.PRO= app.front.PRO;%pull rod wishbone end (to be found)
lf.BD = app.front.BD;
lf.Dbody =   app.front.DBody;
lf.BellAxis = app.front.BellAxis;
lf.rcf=app.front.rcf;%------------------------------------------------------roll center front
lf.rcr=app.front.rcr;%------------------------------------------------------roll center rear

%Left Rear tyre
lr.uj1=app.rear.uj1;%----------------------------------------upper inboard point 1
lr.uj2=app.rear.uj2;%-------------------------------------upper inboard point 2
lr.lj1=app.rear.lj1;%--------------------------------------lower inboard point 1
lr.lj2=app.rear.lj2;%-------------------------------------lower inboard point 2
lr.lbj=app.rear.lbj;%--------------------------------------lower ball joint
lr.ubj=app.rear.ubj;%---------------------------------------upper ball joint
lr.TRI=app.rear.TRI;%-----------------------------------tie rod chassis point
lr.TRO=app.rear.TRO;%-------------steering arm point (where tie rod attatches)
lr.C=app.rear.Camber;
lr.Toe=app.rear.Toe;
lr.tcp=app.rear.tcp;
lr.whlcenter=[wheelradius*sind(lr.C)*sind(lr.Toe),wheelradius*sind(lr.C)*cosd(lr.Toe),wheelradius*cosd(lr.C)]+lr.tcp;%-------------------------------define wheel center
lr.spindlevec=[-cosd(lr.C)*sind(lr.Toe),-cosd(lr.C)*cosd(lr.Toe),sind(lr.C)];
lr.PRI=app.rear.PRI;%pull rod bellcrank point (to be found)
lr.PRO= app.rear.PRO;%pull rod wishbone end (to be found)
lr.BD = app.rear.BD;
lr.Dbody =   app.rear.Dbody;
lr.BellAxis = app.rear.BellAxis;
lr.rcf=app.rear.rcf;%------------------------------------------------------roll center front
lr.rcr=app.rear.rcr;%------------------------------------------------------roll center rear
%Right Front tyre
rf.uj1=changeup(app,lf.uj1);%----------------------------------------upper inboard point 1
rf.uj2=changeup(app,lf.uj2);%-------------------------------------upper inboard point 2
rf.lj1=changeup(app,lf.lj1);%--------------------------------------lower inboard point 1
rf.lj2=changeup(app,lf.lj2);%-------------------------------------lower inboard point 2
rf.lbj=changeup(app,lf.lbj);%--------------------------------------lower ball joint
rf.ubj=changeup(app,lf.ubj);%---------------------------------------upper ball joint
rf.TRI=changeup(app,lf.TRI);%-----------------------------------tie rod chassis point
rf.TRO=changeup(app,lf.TRO);%-------------steering arm point (where tie rod attatches)
rf.C=lf.C;
rf.Toe=lf.Toe;
rf.tcp=[lf.tcp(1),-lf.tcp(2),0];
rf.whlcenter=[wheelradius*sind(rf.C)*sind(rf.Toe),-wheelradius*sind(rf.C)*cosd(rf.Toe),wheelradius*cosd(rf.C)]+rf.tcp;%-------------------------------define wheel center
rf.spindlevec=[-cosd(rf.C)*sind(rf.Toe),cosd(rf.C)*cosd(rf.Toe),sind(rf.C)];
rf.PRI=changeup(app,lf.PRI);%pull rod bellcrank point (to be found)
rf.PRO= changeup(app,lf.PRO);%pull rod wishbone end (to be found)
rf.BD = changeup(app,lf.BD);
rf.Dbody =   changeup(app,lf.Dbody);
rf.BellAxis = changeup(app,lf.BellAxis);
rf.rcf=changeup(app,lf.rcf);%------------------------------------------------------roll center front
rf.rcr=changeup(app,lf.rcr);%------------------------------------------------------roll center rear

% %Right Rear Tyre
rr.uj1=changeup(app,lr.uj1);
rr.uj2=changeup(app,lr.uj2);
rr.lj1=changeup(app,lr.lj1);
rr.lj2=changeup(app,lr.lj2);
rr.ubj= changeup(app,lr.ubj);%upper ball joint
rr.lbj=changeup(app,lr.lbj);%lower ball joint
rr.TRI=changeup(app,lr.TRI); %tie rod chassis point (to be found)
rr.TRO=changeup(app,lr.TRO); %tie rod knuckle point (to be found)
rr.C=lr.C;
rr.Toe=lr.Toe;
rr.tcp=changeup(app,lr.tcp);%tire contact point (to be found)
rr.whlcenter=[wheelradius*sind(rr.C)*sind(rr.Toe),-wheelradius*sind(rr.C)*cosd(rr.Toe),wheelradius*cosd(rr.C)]+rr.tcp;%-------------------------------define wheel center
rr.spindlevec=[-cosd(rr.C)*sind(rr.Toe),cosd(rr.C)*cosd(rr.Toe),sind(rr.C)];
rr.PRI=changeup(app,lr.PRI);%push rod bellcrank point (to be found)
rr.PRO= changeup(app,lr.PRO);%push rod wishbone end (to be found)
rr.BD =changeup(app,lr.BD); %bellcrank damper coordinates
rr.Dbody =changeup(app,lr.Dbody); %damper chassis coordinates
rr.BellAxis =changeup(app,lr.BellAxis); %bellcrank axis coordinates
rr.rcf=lr.rcf;%------------------------------------------------------roll center front
rr.rcr=lr.rcr;%------------------------------------------------------roll center rear
% % lftyre=zeroes(fix(num),8);
% % rftyre=zeroes(num,8);
% % lrtyre=zeroes(num,8);
% % rrtyre=zeroes(num,8);
if (mode==1)
    j=1;
    for i=linspace(In,-In,num)
        lftyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[i,0,0],lf)];
        lrtyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[i,0,0],lr)];
        rftyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[i,0,0],rf)];
        rrtyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[i,0,0],rr)];
        j=j+1;
    end

end
if (mode==2)
    j=1;
    for i=linspace(In,-In,num)
        lftyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[0,i,0],lf)];
        lrtyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[0,0,0],lr)];
        rftyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[0,i,0],rf)];
        rrtyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[0,0,0],rr)];
        j=j+1;
    end
    
end
if (mode==3)
    j=1;
    for i=linspace(In,-In,num)
        lftyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[0,0,i],lf)];
        lrtyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[0,0,i],lr)];
        rftyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[0,0,i],rf)];
        rrtyre(j,:)=[i,calcsusp(app,[10;10;10;10;10;10],[0,0,i],rr)];
        j=j+1;
    end
    
end
main=cat(3,lftyre,rftyre,lrtyre,rrtyre);
end
function X=rotation(~,inb1,inb2,balljoint,alpha)
axis=inb2-inb1;
vec1=balljoint-inb1;
N=[0 ,-axis(3), axis(2);axis(3), 0 ,-axis(1);-axis(2),axis(1), 0]/norm(axis);
rotvec=vec1*((N^2)*(1-cosd(alpha))+eye(3)+N*sind(alpha));
X=inb1+rotvec;
%this is used specifically for the inboard point and ball joint calculation
end
function q=newton(app,fun,q0,m,ty)
f0=feval(fun,app,q0,m,ty);
count=0;
h=1e-6;
n=length(q0);
e=eye(n)*h;
D=e;

while((norm(f0)>1e-6)*(count<6000))
    for k=1:n
        D(:,k)=(feval(fun,app,q0+e(:,k),m,ty)-f0)/h;
    end
    q0=q0-D\f0;
    f0=feval(fun,app,q0,m,ty);
    count=count+1;
end

if count==6000
    q=q0/0;
else
    q=q0;
end 
end
function distance = pointPlaneDistance(~,point, planeCoefficients)
    % point is a 3-element vector representing (x, y, z) coordinates
    % planeCoefficients is a 4-element vector representing (A, B, C, D) coefficients of the plane equation
    
    A = planeCoefficients(1);
    B = planeCoefficients(2);
    C = planeCoefficients(3);
    D = planeCoefficients(4);
    
    x = point(1);
    y = point(2);
    z = point(3);
    
    % Calculate the distance using the formula
    distance = abs(A * x + B * y + C * z + D) / sqrt(A^2 + B^2 + C^2);
end
function y=rotate3D(~,vec,axis,A)
N=[0 ,-axis(3), axis(2);axis(3), 0 ,-axis(1);-axis(2),axis(1), 0]/norm(axis);
y=vec*((N^2)*(1-cosd(A))+eye(3)+N*sind(A));
% basic axis angle rotation code
end
function coords=calcsusp(app,T,In,ty)
an=newton(app,'sustravel',T,In,ty);
coords=finsusp(app,an,In,ty);
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
           
            app.ans=LotusShark(app);
            if(app.ModeEditField.Value==1)
            app.UITable.ColumnName{1}='Bump';
            end
            if(app.ModeEditField.Value==2)
            app.UITable.ColumnName{1}='Steer Travel';
            end
            if(app.ModeEditField.Value==3)
            app.UITable.ColumnName{1}='Roll';
            end
            app.ResultsPanel.Visible="on";

        end

        % Button pushed function: DisplayforLeftRearTyreButton
        function DisplayforLeftRearTyreButtonPushed(app, event)
            app.UITable.Data=app.ans(:,:,3);
        end

        % Button pushed function: DisplayforRightRearTyreButton
        function DisplayforRightRearTyreButtonPushed(app, event)
            app.UITable.Data=app.ans(:,:,4);
        end

        % Button pushed function: DisplayforLeftFrontTyreButton
        function DisplayforLeftFrontTyreButtonPushed(app, event)
            app.UITable.Data=app.ans(:,:,1);
           
        end

        % Button pushed function: DisplayforRightFrontTyreButton
        function DisplayforRightFrontTyreButtonPushed(app, event)
            app.UITable.Data=app.ans(:,:,2);
        end

        % Value changed function: LowerwishbonefrontpivotEditField
        function LowerwishbonefrontpivotEditFieldValueChanged(app, event)
            value = app.LowerwishbonefrontpivotEditField.Value;
            app.front.lj1=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: TyreContactpointEditField_2
        function TyreContactpointEditField_2ValueChanged(app, event)
            value = app.TyreContactpointEditField_2.Value;
            app.rear.tcp=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: TyreContactpointEditField
        function TyreContactpointEditFieldValueChanged(app, event)
            value = app.TyreContactpointEditField.Value;
            app.front.tcp=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: RollCenterEditField_2
        function RollCenterEditField_2ValueChanged(app, event)
            value = app.RollCenterEditField_2.Value;
            app.front.rcr=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
            app.rear.rcr=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: BellCrankRotatingpointEditField_2
        function BellCrankRotatingpointEditField_2ValueChanged(app, event)
            value = app.BellCrankRotatingpointEditField_2.Value;
            app.rear.BellAxis=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: StaticToeEditField_2
        function StaticToeEditField_2ValueChanged(app, event)
            value = app.StaticToeEditField_2.Value;
            app.rear.Toe=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: StaticCamberEditField_2
        function StaticCamberEditField_2ValueChanged(app, event)
            value = app.StaticCamberEditField_2.Value;
            app.rear.Camber=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: DampertoBellCrankpointEditField_2
        function DampertoBellCrankpointEditField_2ValueChanged(app, event)
            value = app.DampertoBellCrankpointEditField_2.Value;
            app.rear.BD=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: DampertobodypointEditField_2
        function DampertobodypointEditField_2ValueChanged(app, event)
            value = app.DampertobodypointEditField_2.Value;
            app.rear.Dbody=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: InnertrackrodballjointEditField_2
        function InnertrackrodballjointEditField_2ValueChanged(app, event)
            value = app.InnertrackrodballjointEditField_2.Value;
            app.rear.TRI=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: OutertrackrodballjointEditField_2
        function OutertrackrodballjointEditField_2ValueChanged(app, event)
            value = app.OutertrackrodballjointEditField_2.Value;
            app.rear.TRO=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: UpperwishbonefrontpivotEditField_2
        function UpperwishbonefrontpivotEditField_2ValueChanged(app, event)
            value = app.UpperwishbonefrontpivotEditField_2.Value;
            app.rear.uj1=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: PushrodBellCrankendEditField_2
        function PushrodBellCrankendEditField_2ValueChanged(app, event)
            value = app.PushrodBellCrankendEditField_2.Value;
            app.rear.PRI=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: PushrodwishboneendEditField_2
        function PushrodwishboneendEditField_2ValueChanged(app, event)
            value = app.PushrodwishboneendEditField_2.Value;
            app.rear.PRO=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: UpperwishboneouterballjointEditField_2
        function UpperwishboneouterballjointEditField_2ValueChanged(app, event)
            value = app.UpperwishboneouterballjointEditField_2.Value;
            app.rear.ubj=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: UpperwishbonerearpivotEditField_2
        function UpperwishbonerearpivotEditField_2ValueChanged(app, event)
            value = app.UpperwishbonerearpivotEditField_2.Value;
            app.rear.uj2=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: LowerwishboneouterballjointEditField_2
        function LowerwishboneouterballjointEditField_2ValueChanged(app, event)
            value = app.LowerwishboneouterballjointEditField_2.Value;
            app.rear.lbj=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: LowerwishbonerearpivotEditField_2
        function LowerwishbonerearpivotEditField_2ValueChanged(app, event)
            value = app.LowerwishbonerearpivotEditField_2.Value;
            app.rear.lj2=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: LowerwishbonefrontpivotEditField_2
        function LowerwishbonefrontpivotEditField_2ValueChanged(app, event)
            value = app.LowerwishbonefrontpivotEditField_2.Value;
            app.rear.lj1=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: RollCenterEditField
        function RollCenterEditFieldValueChanged(app, event)
            value = app.RollCenterEditField.Value;
            app.front.rcf=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
            app.rear.rcf=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: BellCrankRotatingpointEditField
        function BellCrankRotatingpointEditFieldValueChanged(app, event)
            value = app.BellCrankRotatingpointEditField.Value;
            app.front.BellAxis=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: StaticToeEditField
        function StaticToeEditFieldValueChanged(app, event)
            value = app.StaticToeEditField.Value;
            app.front.Toe=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: StaticCamberEditField
        function StaticCamberEditFieldValueChanged(app, event)
            value = app.StaticCamberEditField.Value;
            app.front.Camber=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: DampertoBellCrankpointEditField
        function DampertoBellCrankpointEditFieldValueChanged(app, event)
            value = app.DampertoBellCrankpointEditField.Value;
            app.front.BD=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: DampertobodypointEditField
        function DampertobodypointEditFieldValueChanged(app, event)
            value = app.DampertobodypointEditField.Value;
            app.front.DBody=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: InnertrackrodballjointEditField
        function InnertrackrodballjointEditFieldValueChanged(app, event)
            value = app.InnertrackrodballjointEditField.Value;
            app.front.TRI=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: OutertrackrodballjointEditField
        function OutertrackrodballjointEditFieldValueChanged(app, event)
            value = app.OutertrackrodballjointEditField.Value;
            app.front.TRO=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: UpperwishbonefrontpivotEditField
        function UpperwishbonefrontpivotEditFieldValueChanged(app, event)
            value = app.UpperwishbonefrontpivotEditField.Value;
            app.front.uj1=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: PushrodBellCrankendEditField
        function PushrodBellCrankendEditFieldValueChanged(app, event)
            value = app.PushrodBellCrankendEditField.Value;
            app.front.PRI=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: PushrodwishboneendEditField
        function PushrodwishboneendEditFieldValueChanged(app, event)
            value = app.PushrodwishboneendEditField.Value;
            app.front.PRO=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: UpperwishboneouterballjointEditField
        function UpperwishboneouterballjointEditFieldValueChanged(app, event)
            value = app.UpperwishboneouterballjointEditField.Value;
            app.front.ubj=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: UpperwishbonerearpivotEditField
        function UpperwishbonerearpivotEditFieldValueChanged(app, event)
            value = app.UpperwishbonerearpivotEditField.Value;
            app.front.uj2=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: LowerwishboneouterballjointEditField
        function LowerwishboneouterballjointEditFieldValueChanged(app, event)
            value = app.LowerwishboneouterballjointEditField.Value;
            app.front.lbj=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Value changed function: LowerwishbonerearpivotEditField
        function LowerwishbonerearpivotEditFieldValueChanged(app, event)
            value = app.LowerwishbonerearpivotEditField.Value;
            app.front.lj2=str2double(regexp(value,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'));
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            app.EnterSuspensionGeometryCoordinatesPanel.Visible="off";
            app.EnterSuspensionGeometryCoordinatesPanel.Enable="off";
            app.LeftPanel.Visible="on";
        end

        % Value changed function: WheelRadiusEditField
        function WheelRadiusEditFieldValueChanged(app, event)
            value = app.WheelRadiusEditField.Value;
            app.whlradius=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LeftPanel.Visible = 'off';
            app.ValueEditFieldLabel = uilabel(app.LeftPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ValueEditField.RoundFractionalValues = 'on';
            app.ModeEditFieldLabel = uilabel(app.LeftPanel);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.ModeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ModeEditField.RoundFractionalValues = 'on';
            app.NumberofvaluesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofvaluesEditFieldLabel.Text = 'Number of values';
            app.NumberofvaluesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofvaluesEditField.RoundFractionalValues = 'on';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsPanel = uipanel(app.LeftPanel);
            app.ResultsPanel.Title = 'Results';
            app.DisplayforLeftFrontTyreButton = uibutton(app.ResultsPanel, 'push');
            app.DisplayforLeftFrontTyreButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayforLeftFrontTyreButtonPushed, true);
            app.DisplayforLeftFrontTyreButton.Text = 'Display for Left Front Tyre';
            app.UITable = uitable(app.ResultsPanel);
            app.UITable.ColumnName = {'Parameter'; 'Camber(deg)'; 'Toe(deg)'; 'Castor(deg)'; 'KPI(deg)'; 'Spring Travel(mm)'; 'Half Track change(mm)'; 'Half Wheelbase Change(mm)'};
            app.UITable.RowName = {};
            app.DisplayforRightFrontTyreButton = uibutton(app.ResultsPanel, 'push');
            app.DisplayforRightFrontTyreButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayforRightFrontTyreButtonPushed, true);
            app.DisplayforRightFrontTyreButton.Text = 'Display for Right Front Tyre';
            app.DisplayforRightRearTyreButton = uibutton(app.ResultsPanel, 'push');
            app.DisplayforRightRearTyreButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayforRightRearTyreButtonPushed, true);
            app.DisplayforRightRearTyreButton.Text = 'Display for Right Rear Tyre';
            app.DisplayforLeftRearTyreButton = uibutton(app.ResultsPanel, 'push');
            app.DisplayforLeftRearTyreButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayforLeftRearTyreButtonPushed, true);
            app.DisplayforLeftRearTyreButton.Text = 'Display for Left Rear Tyre';
            app.ModespecifiesthetypeofparameterLabel = uilabel(app.LeftPanel);
            app.ModespecifiesthetypeofparameterLabel.Text = {'Mode specifies the type of parameter for'; 'which the outputs have to be calculated '; 'for. '; 'Mode=1-- Bump Travel(mm)'; 'Mode=2--Steer Travel(mm)'; 'Mode=3--Roll(deg)'; ''; 'Value specifies the interval (-input,input) '; 'in which the outputs will be calculated'; ''; 'Number of Values specifies the number of'; 'values in the interval specified for which '; 'output will be dispayed'; ''; 'Example input:-'; 'Mode=3'; 'Value=2'; 'Number of Values=5'; 'These will have the parameter as Roll'; 'and will display outputs when roll equals'; '-2,-1,0,1,2.'};
            app.EnterSuspensionGeometryCoordinatesPanel = uipanel(app.UIFigure);
            app.EnterSuspensionGeometryCoordinatesPanel.Title = 'Enter Suspension Geometry Coordinates';
            app.LowerwishbonefrontpivotEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.LowerwishbonefrontpivotEditFieldLabel.Text = 'Lower wishbone front pivot';
            app.LowerwishbonefrontpivotEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.LowerwishbonefrontpivotEditField.ValueChangedFcn = createCallbackFcn(app, @LowerwishbonefrontpivotEditFieldValueChanged, true);
            app.Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.Label.Text = {'Assuming standard convention and midpoint of front track as center, enter the cooridnates of the LEFT tyre in the format [x,y,z]'; 'Example:- Tyre contact point=[0,600,0]'; 'All values in either degrees or millimeter'};
            app.LowerwishbonerearpivotEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.LowerwishbonerearpivotEditFieldLabel.Text = 'Lower wishbone rear pivot';
            app.LowerwishbonerearpivotEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.LowerwishbonerearpivotEditField.ValueChangedFcn = createCallbackFcn(app, @LowerwishbonerearpivotEditFieldValueChanged, true);
            app.LowerwishboneouterballjointEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.LowerwishboneouterballjointEditFieldLabel.Text = 'Lower wishbone outer ball joint';
            app.LowerwishboneouterballjointEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.LowerwishboneouterballjointEditField.ValueChangedFcn = createCallbackFcn(app, @LowerwishboneouterballjointEditFieldValueChanged, true);
            app.UpperwishbonerearpivotEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.UpperwishbonerearpivotEditFieldLabel.Text = 'Upper wishbone rear pivot';
            app.UpperwishbonerearpivotEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.UpperwishbonerearpivotEditField.ValueChangedFcn = createCallbackFcn(app, @UpperwishbonerearpivotEditFieldValueChanged, true);
            app.UpperwishboneouterballjointEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.UpperwishboneouterballjointEditFieldLabel.Text = 'Upper wishbone outer ball joint';
            app.UpperwishboneouterballjointEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.UpperwishboneouterballjointEditField.ValueChangedFcn = createCallbackFcn(app, @UpperwishboneouterballjointEditFieldValueChanged, true);
            app.PushrodwishboneendEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.PushrodwishboneendEditFieldLabel.Text = 'Push rod wishbone end';
            app.PushrodwishboneendEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.PushrodwishboneendEditField.ValueChangedFcn = createCallbackFcn(app, @PushrodwishboneendEditFieldValueChanged, true);
            app.PushrodBellCrankendEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.PushrodBellCrankendEditFieldLabel.Text = 'Push rod Bell Crank end';
            app.PushrodBellCrankendEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.PushrodBellCrankendEditField.ValueChangedFcn = createCallbackFcn(app, @PushrodBellCrankendEditFieldValueChanged, true);
            app.UpperwishbonefrontpivotEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.UpperwishbonefrontpivotEditFieldLabel.Text = 'Upper wishbone front pivot';
            app.UpperwishbonefrontpivotEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.UpperwishbonefrontpivotEditField.ValueChangedFcn = createCallbackFcn(app, @UpperwishbonefrontpivotEditFieldValueChanged, true);
            app.OutertrackrodballjointEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.OutertrackrodballjointEditFieldLabel.Text = 'Outer track rod ball joint';
            app.OutertrackrodballjointEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.OutertrackrodballjointEditField.ValueChangedFcn = createCallbackFcn(app, @OutertrackrodballjointEditFieldValueChanged, true);
            app.InnertrackrodballjointEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.InnertrackrodballjointEditFieldLabel.Text = 'Inner track rod ball joint';
            app.InnertrackrodballjointEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.InnertrackrodballjointEditField.ValueChangedFcn = createCallbackFcn(app, @InnertrackrodballjointEditFieldValueChanged, true);
            app.FrontGeometryLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.FrontGeometryLabel.Text = 'Front Geometry';
            app.DampertobodypointEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.DampertobodypointEditFieldLabel.Text = 'Damper to body point';
            app.DampertobodypointEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.DampertobodypointEditField.ValueChangedFcn = createCallbackFcn(app, @DampertobodypointEditFieldValueChanged, true);
            app.DampertoBellCrankpointEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.DampertoBellCrankpointEditFieldLabel.Text = 'Damper to Bell Crank point';
            app.DampertoBellCrankpointEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.DampertoBellCrankpointEditField.ValueChangedFcn = createCallbackFcn(app, @DampertoBellCrankpointEditFieldValueChanged, true);
            app.StaticCamberEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.StaticCamberEditFieldLabel.Text = 'Static Camber';
            app.StaticCamberEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.StaticCamberEditField.ValueChangedFcn = createCallbackFcn(app, @StaticCamberEditFieldValueChanged, true);
            app.StaticToeEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.StaticToeEditFieldLabel.Text = 'Static Toe';
            app.StaticToeEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.StaticToeEditField.ValueChangedFcn = createCallbackFcn(app, @StaticToeEditFieldValueChanged, true);
            app.BellCrankRotatingpointEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.BellCrankRotatingpointEditFieldLabel.Text = 'Bell Crank Rotating point';
            app.BellCrankRotatingpointEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.BellCrankRotatingpointEditField.ValueChangedFcn = createCallbackFcn(app, @BellCrankRotatingpointEditFieldValueChanged, true);
            app.RollCenterEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.RollCenterEditFieldLabel.Text = 'Roll Center';
            app.RollCenterEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.RollCenterEditField.ValueChangedFcn = createCallbackFcn(app, @RollCenterEditFieldValueChanged, true);
            app.LowerwishbonefrontpivotEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.LowerwishbonefrontpivotEditField_2Label.Text = 'Lower wishbone front pivot';
            app.LowerwishbonefrontpivotEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.LowerwishbonefrontpivotEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowerwishbonefrontpivotEditField_2ValueChanged, true);
            app.LowerwishbonerearpivotEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.LowerwishbonerearpivotEditField_2Label.Text = 'Lower wishbone rear pivot';
            app.LowerwishbonerearpivotEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.LowerwishbonerearpivotEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowerwishbonerearpivotEditField_2ValueChanged, true);
            app.LowerwishboneouterballjointEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.LowerwishboneouterballjointEditField_2Label.Text = 'Lower wishbone outer ball joint';
            app.LowerwishboneouterballjointEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.LowerwishboneouterballjointEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowerwishboneouterballjointEditField_2ValueChanged, true);
            app.UpperwishbonerearpivotEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.UpperwishbonerearpivotEditField_2Label.Text = 'Upper wishbone rear pivot';
            app.UpperwishbonerearpivotEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.UpperwishbonerearpivotEditField_2.ValueChangedFcn = createCallbackFcn(app, @UpperwishbonerearpivotEditField_2ValueChanged, true);
            app.UpperwishboneouterballjointEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.UpperwishboneouterballjointEditField_2Label.Text = 'Upper wishbone outer ball joint';
            app.UpperwishboneouterballjointEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.UpperwishboneouterballjointEditField_2.ValueChangedFcn = createCallbackFcn(app, @UpperwishboneouterballjointEditField_2ValueChanged, true);
            app.PushrodwishboneendEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.PushrodwishboneendEditField_2Label.Text = 'Push rod wishbone end';
            app.PushrodwishboneendEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.PushrodwishboneendEditField_2.ValueChangedFcn = createCallbackFcn(app, @PushrodwishboneendEditField_2ValueChanged, true);
            app.PushrodBellCrankendEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.PushrodBellCrankendEditField_2Label.Text = 'Push rod Bell Crank end';
            app.PushrodBellCrankendEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.PushrodBellCrankendEditField_2.ValueChangedFcn = createCallbackFcn(app, @PushrodBellCrankendEditField_2ValueChanged, true);
            app.UpperwishbonefrontpivotEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.UpperwishbonefrontpivotEditField_2Label.Text = 'Upper wishbone front pivot';
            app.UpperwishbonefrontpivotEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.UpperwishbonefrontpivotEditField_2.ValueChangedFcn = createCallbackFcn(app, @UpperwishbonefrontpivotEditField_2ValueChanged, true);
            app.OutertrackrodballjointEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.OutertrackrodballjointEditField_2Label.Text = 'Outer track rod ball joint';
            app.OutertrackrodballjointEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.OutertrackrodballjointEditField_2.ValueChangedFcn = createCallbackFcn(app, @OutertrackrodballjointEditField_2ValueChanged, true);
            app.InnertrackrodballjointEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.InnertrackrodballjointEditField_2Label.Text = 'Inner track rod ball joint';
            app.InnertrackrodballjointEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.InnertrackrodballjointEditField_2.ValueChangedFcn = createCallbackFcn(app, @InnertrackrodballjointEditField_2ValueChanged, true);
            app.DampertobodypointEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.DampertobodypointEditField_2Label.Text = 'Damper to body point';
            app.DampertobodypointEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.DampertobodypointEditField_2.ValueChangedFcn = createCallbackFcn(app, @DampertobodypointEditField_2ValueChanged, true);
            app.DampertoBellCrankpointEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.DampertoBellCrankpointEditField_2Label.Text = 'Damper to Bell Crank point';
            app.DampertoBellCrankpointEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.DampertoBellCrankpointEditField_2.ValueChangedFcn = createCallbackFcn(app, @DampertoBellCrankpointEditField_2ValueChanged, true);
            app.StaticCamberEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.StaticCamberEditField_2Label.Text = 'Static Camber';
            app.StaticCamberEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.StaticCamberEditField_2.ValueChangedFcn = createCallbackFcn(app, @StaticCamberEditField_2ValueChanged, true);
            app.StaticToeEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.StaticToeEditField_2Label.Text = 'Static Toe';
            app.StaticToeEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.StaticToeEditField_2.ValueChangedFcn = createCallbackFcn(app, @StaticToeEditField_2ValueChanged, true);
            app.BellCrankRotatingpointEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.BellCrankRotatingpointEditField_2Label.Text = 'Bell Crank Rotating point';
            app.BellCrankRotatingpointEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.BellCrankRotatingpointEditField_2.ValueChangedFcn = createCallbackFcn(app, @BellCrankRotatingpointEditField_2ValueChanged, true);
            app.RearGeometryLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.RearGeometryLabel.Text = 'Rear Geometry';
            app.RollCenterEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.RollCenterEditField_2Label.Text = 'Roll Center';
            app.RollCenterEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.RollCenterEditField_2.ValueChangedFcn = createCallbackFcn(app, @RollCenterEditField_2ValueChanged, true);
            app.TyreContactpointEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.TyreContactpointEditFieldLabel.Text = 'Tyre Contact point';
            app.TyreContactpointEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.TyreContactpointEditField.ValueChangedFcn = createCallbackFcn(app, @TyreContactpointEditFieldValueChanged, true);
            app.TyreContactpointEditField_2Label = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.TyreContactpointEditField_2Label.Text = 'Tyre Contact point';
            app.TyreContactpointEditField_2 = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'text');
            app.TyreContactpointEditField_2.ValueChangedFcn = createCallbackFcn(app, @TyreContactpointEditField_2ValueChanged, true);
            app.CalculateButton_2 = uibutton(app.EnterSuspensionGeometryCoordinatesPanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.roll_center_heightLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.roll_center_heightLabel.Text = '<-([0,0,roll_center_height])';
            app.wheelbase0roll_center_heightLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.wheelbase0roll_center_heightLabel.Text = '([-wheelbase,0,roll_center_height])->';
            app.WheelRadiusEditFieldLabel = uilabel(app.EnterSuspensionGeometryCoordinatesPanel);
            app.WheelRadiusEditFieldLabel.Text = 'Wheel Radius';
            app.WheelRadiusEditField = uieditfield(app.EnterSuspensionGeometryCoordinatesPanel, 'numeric');
            app.WheelRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @WheelRadiusEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SuspensionSolverBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end